classdef Final_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        StatusMessageTextAreaLabel    matlab.ui.control.Label
        StatusMessageTextArea         matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        Task1Tab                      matlab.ui.container.Tab
        PieceTypeDropDownLabel        matlab.ui.control.Label
        PieceTypeDropDown             matlab.ui.control.DropDown
        ChoosethetypeofpieceontheboardLabel  matlab.ui.control.Label
        ChooseLocationtomovetoLabel   matlab.ui.control.Label
        RowLabel                      matlab.ui.control.Label
        RowEditField                  matlab.ui.control.NumericEditField
        ColumnEditFieldLabel          matlab.ui.control.Label
        ColumnEditField               matlab.ui.control.NumericEditField
        NoteLabel                     matlab.ui.control.Label
        ValidRowColumnlocationsrangefrom11to88Label  matlab.ui.control.Label
        MoveButton                    matlab.ui.control.Button
        Task2Tab                      matlab.ui.container.Tab
        ChoosetheopponentpiecetobeeliminatedLabel  matlab.ui.control.Label
        OpponentPieceDropDownLabel    matlab.ui.control.Label
        OpponentPieceDropDown         matlab.ui.control.DropDown
        Task3Tab                      matlab.ui.container.Tab
        ChoosetheuserpiecetochecktheopponentKingLabel  matlab.ui.control.Label
        UserPieceDropDownLabel        matlab.ui.control.Label
        UserPieceDropDown             matlab.ui.control.DropDown
        Task4Tab                      matlab.ui.container.Tab
        AvoidCheckButton              matlab.ui.control.Button
        PiecesTab                     matlab.ui.container.Tab
        AddPieceButton                matlab.ui.control.Button
        ColorEditFieldLabel           matlab.ui.control.Label
        ColorEditField                matlab.ui.control.EditField
        ShapeEditFieldLabel           matlab.ui.control.Label
        ShapeEditField                matlab.ui.control.EditField
        PieceTypeEditFieldLabel       matlab.ui.control.Label
        PieceTypeEditField            matlab.ui.control.EditField
        AllignmentEditFieldLabel      matlab.ui.control.Label
        AllignmentEditField           matlab.ui.control.EditField
        AddpieceafterenteringdataineveryfieldLabel  matlab.ui.control.Label
        PawnRookBishopLabel           matlab.ui.control.Label
        KnightKingorQueenLabel        matlab.ui.control.Label
        PlayerorOpponentLabel         matlab.ui.control.Label
        RedGreenBlueorYellowLabel     matlab.ui.control.Label
        CircleSquareorTriangleLabel   matlab.ui.control.Label
        ChessScenarioSimulationLabel  matlab.ui.control.Label
        InformationPanel              matlab.ui.container.Panel
        TabGroup2                     matlab.ui.container.TabGroup
        T1Tab                         matlab.ui.container.Tab
        Task1TextAreaLabel            matlab.ui.control.Label
        Task1TextArea                 matlab.ui.control.TextArea
        T2Tab                         matlab.ui.container.Tab
        Task2TextArea_4Label          matlab.ui.control.Label
        Task2TextArea_4               matlab.ui.control.TextArea
        T3Tab                         matlab.ui.container.Tab
        Task3TextAreaLabel            matlab.ui.control.Label
        Task3TextArea                 matlab.ui.control.TextArea
        T4Tab                         matlab.ui.container.Tab
        Task4TextAreaLabel            matlab.ui.control.Label
        Task4TextArea                 matlab.ui.control.TextArea
        MiscTab                       matlab.ui.container.Tab
        MiscTextAreaLabel             matlab.ui.control.Label
        MiscTextArea                  matlab.ui.control.TextArea
        ClawHeightEditFieldLabel      matlab.ui.control.Label
        ClawHeightEditField           matlab.ui.control.NumericEditField
        Heightmustbebetween1and8inchesLabel  matlab.ui.control.Label
        TakePictureButton             matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: AddPieceButton
        function AddPieceButtonPushed(app, event)
            global gamestate;
            
            global user_color;
            global user_shape;
            global user_piece_type;
            global chosen_allignment;
            
            count = 0;
            
            for i = 1:length(gamestate)
                if (strcmp(gamestate(i).COLOR,user_color) == 1) && ...
                        (strcmp(gamestate(i).SHAPE,user_shape) == 1)
                    gamestate(i).PIECETYPE = user_piece_type;
                    gamestate(i).ALLIGNMENT = chosen_allignment;
                    app.StatusMessageTextArea.Value = 'Piece Added';
                    count = count + 1;
                    break;
                end
                    
            end
            
            % can also do error checks to ensure that an individual
            % piece is not being assigned multiple piecetypes and allignments
            % also, there cannot be multiple of the same type of piece for one
            % allignment
            
            if count == 0
                app.StatusMessageTextArea.Value = 'This piece is not on the board';
            end
            
        end

        % Value changed function: AllignmentEditField
        function AllignmentEditFieldValueChanged(app, event)
            global chosen_allignment;
            chosen_allignment = app.AllignmentEditField.Value;
            
        end

        % Button pushed function: AvoidCheckButton
        function AvoidCheckButtonPushed(app, event)
            global status_0;
            global status_1;
            global status_2;
            global status_3;
            
            avoid_check();
            
            if status_0 == 1
                app.StatusMessageTextArea.Value = 'There is no user king present'; 
            elseif status_1 == 1
                app.StatusMessageTextArea.Value = 'The king is not in check';
            elseif status_2 == 1
                app.StatusMessageTextArea.Value = 'Avoided check';
            elseif status_3 == 1
                app.StatusMessageTextArea.Value = 'Check cannot be avoided. Checkmate';
            end
            
        end

        % Value changed function: ClawHeightEditField
        function ClawHeightEditFieldValueChanged(app, event)
            global claw_height;
            claw_height = app.ClawHeightEditField.Value;
            
            if (claw_height < 1) || (claw_height > 7)
                app.StatusMessageTextArea.Value = 'Invalid claw height';
            end
            
        end

        % Value changed function: ColorEditField
        function ColorEditFieldValueChanged(app, event)
            global user_color;
            user_color = app.ColorEditField.Value;
            
        end

        % Value changed function: ColumnEditField
        function ColumnEditFieldValueChanged(app, event)
            global column;
            column = app.ColumnEditField.Value;
            
            if column >= 1 && column <= 8
                %valid column choice
                app.StatusMessageTextArea.Value = 'Valid Column';
            else
                %invalid, display error
                app.StatusMessageTextArea.Value = 'Invalid Column';
            end
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            % executes the move as long as there isn't an error message
            % and the move is valid based on game logic
            global error;
            global column;
            global row;
            
            if (column >= 1 && column <= 8) && (row >= 1 && row <= 8)
                
              t1_move_piece();
              
              if error == 1
                  app.StatusMessageTextArea.Value = 'Invalid move';
              end
                          
            else
                
              app.StatusMessageTextArea.Value = 'Please enter a valid board space';
              
            end
            
            % task 1 move piece handler function sends piece location
            % and requested location
            % checks for validity
            % changes unit position
        end

        % Value changed function: OpponentPieceDropDown
        function OpponentPieceDropDownValueChanged(app, event)
            global gamestate;
            global t2_error;
            t2_error = 0;
            global op; % the variable that stores info on the opponent piece
            op_piece = app.OpponentPieceDropDown.Value;
            
            count = 0;
            
            for i = 1:length(gamestate)
                if (strcmp(gamestate(i).PIECETYPE, op_piece) == 1) && ...
                        (strcmp(gamestate(i).ALLIGNMENT, 'Opponent') == 1)
                    % chosen opponent piece is present
                    op = gamestate(i);
                    t2_elim_piece();
                    count = count + 1;
                    break;
                end
                
            end
            
            if count == 0
                app.StatusMessageTextArea.Value = 'This piece is not present';
                
            else
                if t2_error == 1
                    % piece cannot be elminated in current board state
                    app.StatusMessageTextArea.Value = 'The piece cannot be elminated in the current board state';
                    
                else
                    app.StatusMessageTextArea.Value = 'Piece eliminated';
                end
            end
            
        end

        % Value changed function: PieceTypeDropDown
        function PieceTypeDropDownValueChanged(app, event)
            global piece;
            piece = app.PieceTypeDropDown.Value;
            
            global gamestate;
            
            % check to see if the chosen piece is physically present
            % give an error message if it is not
            
            switch piece
                
                case 'Pawn'
                    if strcmp(gamestate(1).PIECETYPE,'Pawn') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
                    
                case 'Rook'
                    if strcmp(gamestate(1).PIECETYPE,'Rook') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
            
                    
                case 'Bishop'
                    if strcmp(gamestate(1).PIECETYPE,'Bishop') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
                    
                case 'Knight'
                    if strcmp(gamestate(1).PIECETYPE,'Knight') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
                    
                case 'Queen'
                    if strcmp(gamestate(1).PIECETYPE,'Queen') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
                    
                case 'King'
                    if strcmp(gamestate(1).PIECETYPE,'King') ~= 1
                        % invalid chosen piece, display error message
                        app.StatusMessageTextArea.Value = 'Invalid Piece';
                    end
                    
            end
        end

        % Value changed function: PieceTypeEditField
        function PieceTypeEditFieldValueChanged(app, event)
            global user_piece_type;
            user_piece_type = app.PieceTypeEditField.Value;
            
        end

        % Value changed function: RowEditField
        function RowEditFieldValueChanged(app, event)
            global row;
            row = app.RowEditField.Value;
            
            if row >= 1 && row <= 8
                %valid row choice
                app.StatusMessageTextArea.Value = 'Valid Row';
                
            else
                %invalid, display error
                app.StatusMessageTextArea.Value = 'Invalid Row';
            end
            
        end

        % Value changed function: ShapeEditField
        function ShapeEditFieldValueChanged(app, event)
            global user_shape;
            user_shape = app.ShapeEditField.Value;
            
        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
            ip_gamestate(); %runs the image processing code to acquire global gamestate
        end

        % Value changed function: UserPieceDropDown
        function UserPieceDropDownValueChanged(app, event)
            global check_piece;
            global t3_error;
            global t3_error2;
            global t3_error3;
            
            check_piece = app.UserPieceDropDown.Value;
            
            check_king();
            
            if t3_error == 1
                app.StatusMessageTextArea.Value = 'The chosen piece is not present';
            
            elseif t3_error2 == 1
                app.StatusMessageTextArea.Value = 'The opponent king is not present';
                
            elseif t3_error3 == 1
                app.StatusMessageTextArea.Value = 'The king cannot be put in check with the chosen piece';

            elseif t3_error ~= 1 && t3_error2 ~= 1 && t3_error3 ~= 1
                app.StatusMessageTextArea.Value = 'The opponent king is in check';
                
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.StatusMessageTextAreaLabel = uilabel(app.UIFigure);
            app.StatusMessageTextAreaLabel.Text = 'Status Message';
            app.StatusMessageTextArea = uitextarea(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Task1Tab = uitab(app.TabGroup);
            app.Task1Tab.Title = 'Task 1';
            app.PieceTypeDropDownLabel = uilabel(app.Task1Tab);
            app.PieceTypeDropDownLabel.Text = 'Piece Type';
            app.PieceTypeDropDown = uidropdown(app.Task1Tab);
            app.PieceTypeDropDown.Items = {'None', 'Pawn', 'Rook', 'Bishop', 'Knight', 'King', 'Queen'};
            app.PieceTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PieceTypeDropDownValueChanged, true);
            app.PieceTypeDropDown.Value = 'None';
            app.ChoosethetypeofpieceontheboardLabel = uilabel(app.Task1Tab);
            app.ChoosethetypeofpieceontheboardLabel.Text = '1. Choose the type of piece on the board.';
            app.ChooseLocationtomovetoLabel = uilabel(app.Task1Tab);
            app.ChooseLocationtomovetoLabel.Text = '2. Choose Location to move to.';
            app.RowLabel = uilabel(app.Task1Tab);
            app.RowLabel.Text = 'Row';
            app.RowEditField = uieditfield(app.Task1Tab, 'numeric');
            app.RowEditField.ValueChangedFcn = createCallbackFcn(app, @RowEditFieldValueChanged, true);
            app.ColumnEditFieldLabel = uilabel(app.Task1Tab);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.ColumnEditField = uieditfield(app.Task1Tab, 'numeric');
            app.ColumnEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnEditFieldValueChanged, true);
            app.NoteLabel = uilabel(app.Task1Tab);
            app.NoteLabel.Text = '* Note: ';
            app.ValidRowColumnlocationsrangefrom11to88Label = uilabel(app.Task1Tab);
            app.ValidRowColumnlocationsrangefrom11to88Label.Text = 'Valid Row/Column locations range from (1,1) to (8,8).';
            app.MoveButton = uibutton(app.Task1Tab, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Task2Tab = uitab(app.TabGroup);
            app.Task2Tab.Title = 'Task 2';
            app.ChoosetheopponentpiecetobeeliminatedLabel = uilabel(app.Task2Tab);
            app.ChoosetheopponentpiecetobeeliminatedLabel.Text = 'Choose the opponent piece to be eliminated.';
            app.OpponentPieceDropDownLabel = uilabel(app.Task2Tab);
            app.OpponentPieceDropDownLabel.Text = 'Opponent Piece';
            app.OpponentPieceDropDown = uidropdown(app.Task2Tab);
            app.OpponentPieceDropDown.Items = {'None', 'Pawn', 'Rook', 'Bishop', 'Knight', 'King', 'Queen'};
            app.OpponentPieceDropDown.ValueChangedFcn = createCallbackFcn(app, @OpponentPieceDropDownValueChanged, true);
            app.OpponentPieceDropDown.Value = 'None';
            app.Task3Tab = uitab(app.TabGroup);
            app.Task3Tab.Title = 'Task 3';
            app.ChoosetheuserpiecetochecktheopponentKingLabel = uilabel(app.Task3Tab);
            app.ChoosetheuserpiecetochecktheopponentKingLabel.Text = 'Choose the user piece to check the opponent King.';
            app.UserPieceDropDownLabel = uilabel(app.Task3Tab);
            app.UserPieceDropDownLabel.Text = 'User Piece';
            app.UserPieceDropDown = uidropdown(app.Task3Tab);
            app.UserPieceDropDown.Items = {'None', 'Pawn', 'Rook', 'Bishop', 'Knight', 'King', 'Queen'};
            app.UserPieceDropDown.ValueChangedFcn = createCallbackFcn(app, @UserPieceDropDownValueChanged, true);
            app.UserPieceDropDown.Value = 'None';
            app.Task4Tab = uitab(app.TabGroup);
            app.Task4Tab.Title = 'Task 4';
            app.AvoidCheckButton = uibutton(app.Task4Tab, 'push');
            app.AvoidCheckButton.ButtonPushedFcn = createCallbackFcn(app, @AvoidCheckButtonPushed, true);
            app.AvoidCheckButton.Text = 'Avoid Check';
            app.PiecesTab = uitab(app.TabGroup);
            app.PiecesTab.Title = 'Pieces';
            app.AddPieceButton = uibutton(app.PiecesTab, 'push');
            app.AddPieceButton.ButtonPushedFcn = createCallbackFcn(app, @AddPieceButtonPushed, true);
            app.AddPieceButton.Text = 'Add Piece';
            app.ColorEditFieldLabel = uilabel(app.PiecesTab);
            app.ColorEditFieldLabel.Text = 'Color';
            app.ColorEditField = uieditfield(app.PiecesTab, 'text');
            app.ColorEditField.ValueChangedFcn = createCallbackFcn(app, @ColorEditFieldValueChanged, true);
            app.ShapeEditFieldLabel = uilabel(app.PiecesTab);
            app.ShapeEditFieldLabel.Text = 'Shape';
            app.ShapeEditField = uieditfield(app.PiecesTab, 'text');
            app.ShapeEditField.ValueChangedFcn = createCallbackFcn(app, @ShapeEditFieldValueChanged, true);
            app.PieceTypeEditFieldLabel = uilabel(app.PiecesTab);
            app.PieceTypeEditFieldLabel.Text = 'Piece Type';
            app.PieceTypeEditField = uieditfield(app.PiecesTab, 'text');
            app.PieceTypeEditField.ValueChangedFcn = createCallbackFcn(app, @PieceTypeEditFieldValueChanged, true);
            app.AllignmentEditFieldLabel = uilabel(app.PiecesTab);
            app.AllignmentEditFieldLabel.Text = 'Allignment';
            app.AllignmentEditField = uieditfield(app.PiecesTab, 'text');
            app.AllignmentEditField.ValueChangedFcn = createCallbackFcn(app, @AllignmentEditFieldValueChanged, true);
            app.AddpieceafterenteringdataineveryfieldLabel = uilabel(app.PiecesTab);
            app.AddpieceafterenteringdataineveryfieldLabel.Text = '* Add piece after entering data  in every field';
            app.PawnRookBishopLabel = uilabel(app.PiecesTab);
            app.PawnRookBishopLabel.Text = 'Pawn, Rook, Bishop';
            app.KnightKingorQueenLabel = uilabel(app.PiecesTab);
            app.KnightKingorQueenLabel.Text = 'Knight, King, or Queen';
            app.PlayerorOpponentLabel = uilabel(app.PiecesTab);
            app.PlayerorOpponentLabel.Text = 'Player or Opponent';
            app.RedGreenBlueorYellowLabel = uilabel(app.PiecesTab);
            app.RedGreenBlueorYellowLabel.Text = 'Red, Green, Blue, or Yellow';
            app.CircleSquareorTriangleLabel = uilabel(app.PiecesTab);
            app.CircleSquareorTriangleLabel.Text = 'Circle, Square, or Triangle';
            app.ChessScenarioSimulationLabel = uilabel(app.UIFigure);
            app.ChessScenarioSimulationLabel.Text = 'Chess Scenario Simulation';
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.TabGroup2 = uitabgroup(app.InformationPanel);
            app.T1Tab = uitab(app.TabGroup2);
            app.T1Tab.Title = 'T1';
            app.Task1TextAreaLabel = uilabel(app.T1Tab);
            app.Task1TextAreaLabel.Text = 'Task 1';
            app.Task1TextArea = uitextarea(app.T1Tab);
            app.Task1TextArea.Value = {'* Task 1 deals with a single piece on the board'; ''; '- All possible moves are found'; ''; '- User requests the piece to be moved'; ''; '- and location to move the piece to'; ''; '- if invalid, an error message is displayed'; ''; '- otherwise, the move is  completed'};
            app.T2Tab = uitab(app.TabGroup2);
            app.T2Tab.Title = 'T2';
            app.Task2TextArea_4Label = uilabel(app.T2Tab);
            app.Task2TextArea_4Label.Text = 'Task 2';
            app.Task2TextArea_4 = uitextarea(app.T2Tab);
            app.Task2TextArea_4.Value = {'* Task 2 deals with killing an opponent piece'; ''; '- pieces on the board will be identified'; ''; '- user chooses a piece to be eliminated'; ''; '- piece with shortest travel and ability performs the action'; ''};
            app.T3Tab = uitab(app.TabGroup2);
            app.T3Tab.Title = 'T3';
            app.Task3TextAreaLabel = uilabel(app.T3Tab);
            app.Task3TextAreaLabel.Text = 'Task 3';
            app.Task3TextArea = uitextarea(app.T3Tab);
            app.Task3TextArea.Value = {'* Task 3 involves multiple pieces on the board, determined by user'; ''; '- With a given piece, the opponent king needs to be put into a checked position.'};
            app.T4Tab = uitab(app.TabGroup2);
            app.T4Tab.Title = 'T4';
            app.Task4TextAreaLabel = uilabel(app.T4Tab);
            app.Task4TextAreaLabel.Text = 'Task 4';
            app.Task4TextArea = uitextarea(app.T4Tab);
            app.Task4TextArea.Value = {'* In Task 4, a game situation is determined by the opponent with the user''s king in check.'; ''; '- A move must be completed to avoid the check'; ''; '- Multiple moves may exist, move cannot place the king into another checked position'};
            app.MiscTab = uitab(app.TabGroup2);
            app.MiscTab.Title = 'Misc.';
            app.MiscTextAreaLabel = uilabel(app.MiscTab);
            app.MiscTextAreaLabel.Text = 'Misc.';
            app.MiscTextArea = uitextarea(app.MiscTab);
            app.MiscTextArea.Value = {'* Miscellaneous Information:'; ''; '- The "Take Picture" button runs the image processing code and determines the game-state for each task.'; ''; '- The "Status Message" displays information on valid or invalid actions.'; ''; '- An E-stop is incorporated into the system to stop the simulation at anytime if pressed.'; ''; '- The load cell is pressed by the system upon completion of a move as the unit returns to its origin point.'; ''; '- The "Claw Height" field allows the user to choose the height to raise the claw.'};
            app.ClawHeightEditFieldLabel = uilabel(app.InformationPanel);
            app.ClawHeightEditFieldLabel.Text = 'Claw Height';
            app.ClawHeightEditField = uieditfield(app.InformationPanel, 'numeric');
            app.ClawHeightEditField.ValueChangedFcn = createCallbackFcn(app, @ClawHeightEditFieldValueChanged, true);
            app.Heightmustbebetween1and8inchesLabel = uilabel(app.InformationPanel);
            app.Heightmustbebetween1and8inchesLabel.Text = '*Height must be between 1 and 8 inches.';
            app.TakePictureButton = uibutton(app.UIFigure, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Text = 'Take Picture';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Final_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end